classdef DeltaRobotControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        TabGroup2          matlab.ui.container.TabGroup
        Tab_3              matlab.ui.container.Tab
        Panel_3            matlab.ui.container.Panel
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_3           matlab.ui.control.RadioButton
        Button_4           matlab.ui.control.RadioButton
        Button_5           matlab.ui.control.RadioButton
        Button_6           matlab.ui.control.RadioButton
        Label_4            matlab.ui.control.Label
        EditField_7        matlab.ui.control.EditField
        EditField_8        matlab.ui.control.EditField
        EditField_9        matlab.ui.control.EditField
        XLabel_2           matlab.ui.control.Label
        YLabel             matlab.ui.control.Label
        ZLabel             matlab.ui.control.Label
        COMPanel           matlab.ui.container.Panel
        DropDownLabel      matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Button_7           matlab.ui.control.Button
        Button_8           matlab.ui.control.Button
        Lamp               matlab.ui.control.Lamp
        Panel_4            matlab.ui.container.Panel
        XSlider            matlab.ui.control.Slider
        YSliderLabel       matlab.ui.control.Label
        YSlider            matlab.ui.control.Slider
        ZSliderLabel       matlab.ui.control.Label
        ZSlider            matlab.ui.control.Slider
        Panel_5            matlab.ui.container.Panel
        EditField_13       matlab.ui.control.EditField
        EditField_14       matlab.ui.control.EditField
        EditField_15       matlab.ui.control.EditField
        Label_6            matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Label_8            matlab.ui.control.Label
        Slider             matlab.ui.control.Slider
        Slider_2           matlab.ui.control.Slider
        Slider_3           matlab.ui.control.Slider
        EditField_10       matlab.ui.control.EditField
        EditField_11       matlab.ui.control.EditField
        EditField_12       matlab.ui.control.EditField
        XLabel_3           matlab.ui.control.Label
        Panel_6            matlab.ui.container.Panel
        Button_9           matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        Button_10          matlab.ui.control.Button
        Button_11          matlab.ui.control.Button
        Button_12          matlab.ui.control.Button
        STARTButton        matlab.ui.control.Button
        STOPButton         matlab.ui.control.Button
        Button_13          matlab.ui.control.Button
        Switch_2           matlab.ui.control.Switch
        Label_16           matlab.ui.control.Label
        Tab_4              matlab.ui.container.Tab
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes2_2          matlab.ui.control.UIAxes
        UIAxes2_3          matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
        Tab_2              matlab.ui.container.Tab
        TabGroup3          matlab.ui.container.TabGroup
        Tab_6              matlab.ui.container.Tab
        Panel_9            matlab.ui.container.Panel
        UIAxes4            matlab.ui.control.UIAxes
        UIAxes5            matlab.ui.control.UIAxes
        UIAxes6            matlab.ui.control.UIAxes
        UIAxes6_2          matlab.ui.control.UIAxes
        Panel_10           matlab.ui.container.Panel
        SimulinkLabel      matlab.ui.control.Label
        SimulinkButton     matlab.ui.control.Button
        SimulinkPanel      matlab.ui.container.Panel
        Button_15          matlab.ui.control.Button
        Button_16          matlab.ui.control.Button
        Label_15           matlab.ui.control.Label
        Switch             matlab.ui.control.Switch
        Button_17          matlab.ui.control.Button
        Button_18          matlab.ui.control.Button
        Tab_7              matlab.ui.container.Tab
        UIAxes7            matlab.ui.control.UIAxes
        UIAxes7_2          matlab.ui.control.UIAxes
        UIAxes7_4          matlab.ui.control.UIAxes
        UIAxes7_5          matlab.ui.control.UIAxes
        UIAxes8            matlab.ui.control.UIAxes
        UIAxes8_2          matlab.ui.control.UIAxes
        Tab_5              matlab.ui.container.Tab
        Panel_7            matlab.ui.container.Panel
        fEditFieldLabel    matlab.ui.control.Label
        fEditField         matlab.ui.control.EditField
        Label_9            matlab.ui.control.Label
        eEditFieldLabel    matlab.ui.control.Label
        eEditField         matlab.ui.control.EditField
        r_fEditFieldLabel  matlab.ui.control.Label
        r_fEditField       matlab.ui.control.EditField
        r_eEditFieldLabel  matlab.ui.control.Label
        r_eEditField       matlab.ui.control.EditField
        Label_10           matlab.ui.control.Label
        Label_11           matlab.ui.control.Label
        Label_12           matlab.ui.control.Label
        Image              matlab.ui.control.Image
        Panel_8            matlab.ui.container.Panel
        tEditField         matlab.ui.control.EditField
        Label_13           matlab.ui.control.Label
        cLabel             matlab.ui.control.Label
        tEditFieldLabel_2  matlab.ui.control.Label
        tEditField_2       matlab.ui.control.EditField
        Label_14           matlab.ui.control.Label
        tEditFieldLabel_3  matlab.ui.control.Label
        Button_14          matlab.ui.control.Button
    end

    
    properties (Access = private)
        s % Serial 
        L % структура (хранит основные параметры робота)
        StopWhile;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % вывод доступных значений ком портов
            freeports = serialportlist("available");
            app.DropDown.Items = freeports;
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value1 =  round(app.XSlider.Value*10)/10;
            app.EditField_10.Value=num2str(value1);  
            X = str2double(app.EditField_10.Value);
            Y = str2double(app.EditField_11.Value);
            Z = str2double(app.EditField_12.Value);
            [theta1,theta2,theta3] = OZK(X, Y, Z,app.L)
            SendStringToSerial1 = num2str(round(theta1*10)/10);
            SendStringToSerial2 = num2str(round(theta2*10)/10);
            SendStringToSerial3 = num2str(round(theta3*10)/10);
            if app.Switch.Value == '1'
                set_param('SimpleAb_2/Constant','Value',SendStringToSerial1);
                set_param('SimpleAb_2/Constant1','Value',SendStringToSerial2);
                set_param('SimpleAb_2/Constant2','Value',SendStringToSerial3);
                SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            else 
                SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            end
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value2 =  round(app.YSlider.Value*10)/10;
            app.EditField_11.Value=num2str(value2); 
            X = str2double(app.EditField_10.Value);
            Y = str2double(app.EditField_11.Value);
            Z = str2double(app.EditField_12.Value);
            [theta1,theta2,theta3] = OZK(X, Y, Z,app.L)
            SendStringToSerial1 = num2str(round(theta1*10)/10);
            SendStringToSerial2 = num2str(round(theta2*10)/10);
            SendStringToSerial3 = num2str(round(theta3*10)/10);
            if app.Switch.Value == '1'
                set_param('SimpleAb_2/Constant','Value',SendStringToSerial1);
                set_param('SimpleAb_2/Constant1','Value',SendStringToSerial2);
                set_param('SimpleAb_2/Constant2','Value',SendStringToSerial3);
                SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            else
                SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            end            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value3 =  round(app.ZSlider.Value*10)/10;
            app.EditField_12.Value=num2str(value3);  
            X = str2double(app.EditField_10.Value);
            Y = str2double(app.EditField_11.Value);
            Z = str2double(app.EditField_12.Value);
            [theta1,theta2,theta3] = OZK(X, Y, Z,app.L);
            SendStringToSerial1 = num2str(round(theta1*10)/10);
            SendStringToSerial2 = num2str(round(theta2*10)/10);
            SendStringToSerial3 = num2str(round(theta3*10)/10);
            if app.Switch.Value == '1'
                set_param('SimpleAb_2/Constant','Value',SendStringToSerial1);
                set_param('SimpleAb_2/Constant1','Value',SendStringToSerial2);
                set_param('SimpleAb_2/Constant2','Value',SendStringToSerial3);
                SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            else
                SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            theta11 =  round(app.Slider.Value*10)/10;
            app.EditField_13.Value=num2str(theta11); 
            Theta1 =  app.EditField_13.Value;
            Theta2 =  app.EditField_14.Value;
            Theta3 =  app.EditField_15.Value;
            if app.Switch.Value == '1'
                set_param('SimpleAb_2/Constant','Value',Theta1);
                set_param('SimpleAb_2/Constant1','Value',Theta2);
                set_param('SimpleAb_2/Constant2','Value',Theta3);
                SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            else
                SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            theta22 =  round(app.Slider_2.Value*10)/10;
            app.EditField_14.Value=num2str(theta22); 
            Theta1 =  app.EditField_13.Value;
            Theta2 =  app.EditField_14.Value;
            Theta3 =  app.EditField_15.Value;
            if app.Switch.Value == '1'
                set_param('SimpleAb_2/Constant','Value',Theta1);
                set_param('SimpleAb_2/Constant1','Value',Theta2);
                set_param('SimpleAb_2/Constant2','Value',Theta3);
                SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            else
                SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            end
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            theta33 =  round(app.Slider_3.Value*10)/10;
            app.EditField_15.Value=num2str(theta33);  
            Theta1 =  app.EditField_13.Value;
            Theta2 =  app.EditField_14.Value;
            Theta3 =  app.EditField_15.Value;
            if app.Switch.Value == '1'
                set_param('SimpleAb_2/Constant','Value',Theta1);
                set_param('SimpleAb_2/Constant1','Value',Theta2);
                set_param('SimpleAb_2/Constant2','Value',Theta3);
                SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            else
                SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3));
                fprintf(app.s, '%s', SendStringToSerial+'\r\n');
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.Button_3.Value
                app.EditField_7.Value = "";
                app.EditField_8.Value = "";
                app.EditField_9.Value = "";
            elseif app.Button_4.Value
                app.EditField_7.Value = "40*cos(pi*t(:)/15)*cos(pi/6)";
                app.EditField_8.Value = "40*cos(pi*t(:)/15)*sin(pi/6)";
                app.EditField_9.Value = "30*sin(pi*t(:)/15)-130";
            elseif app.Button_5.Value
                app.EditField_7.Value = "45*cos(pi/6*t(:))";
                app.EditField_8.Value = "45*sin(pi/6*t(:))";
                app.EditField_9.Value = "4*pi/10*t(:)-140";
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            T=str2double(app.tEditField.Value); % время моделирования
            %Задание количества участков разбиения
            N=str2double(app.tEditField_2.Value); % количество точек разбиения траектории
            t=0:T/N:T;
            x=str2sym(app.EditField_7.Value);
            y=str2sym(app.EditField_8.Value);
            z=str2sym(app.EditField_9.Value);
            plot3(app.UIAxes,x(:),y(:),z(:),'-','LineWidth',1.5);
            xlabel(app.UIAxes,'X (мм)');
            ylabel(app.UIAxes,'Y (мм)');
            zlabel(app.UIAxes,'Z (мм)');
            grid(app.UIAxes, 'on');  
            hold(app.UIAxes, 'on'); 
            plot3(app.UIAxes,x(:),y(:),z(:),'r*');
            
            % проекция на OXY
            plot(app.UIAxes2,x(:),y(:),'-','LineWidth',1.5);
            grid(app.UIAxes2, 'on');  
            hold(app.UIAxes2, 'on'); 
            plot(app.UIAxes2,x(:),y(:),'r*');
            
            % проекция на OXZ
            plot(app.UIAxes2_2,x(:),z(:),'-','LineWidth',1.5);
            grid(app.UIAxes2_2, 'on');  
            hold(app.UIAxes2_2, 'on'); 
            plot(app.UIAxes2_2,x(:),z(:),'r*');
            
            % проекция на OYZ
            plot(app.UIAxes2_3,y(:),z(:),'-','LineWidth',1.5);
            grid(app.UIAxes2_3, 'on');  
            hold(app.UIAxes2_3, 'on'); 
            plot(app.UIAxes2_3,y(:),z(:),'r*');
            
            i  = 1;
            for t=0:T/N:T
                [Q1,Q2,Q3] = OZK(double(x(i)), double(y(i)), double(z(i)),app.L);
                XYZ(1, i) = double(x(i));
                XYZ(2, i) = double(y(i));
                XYZ(3, i) = double(z(i));
                Q(i, 1) = Q1;
                Q(i, 2) = Q2;
                Q(i, 3) = Q3;
                i=i+1;
            end
            t11=[0:T/N:T]';
            assignin('base','t11',t11);
            assignin('base','Q',Q);
            assignin('base','XYZ',XYZ);
            signal_T1=[t11,Q(:,1)];
            signal_T2=[t11,Q(:,2)]
            signal_T3=[t11,Q(:,3)]
            assignin('base','signal_T1',signal_T1);
            assignin('base','signal_T2',signal_T2);
            assignin('base','signal_T3',signal_T3);
%             assignin('base','signal_T2',signal_T2);
%             assignin('base','signal_T3',signal_T3);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            % Добаволение координат точки в таблицу
            if app.Switch_2.Value
                SlValue = 1;
            else
                SlValue = 0;
            end
            app.UITable.Data(end+1,:) = [str2double(app.EditField_10.Value) str2double(app.EditField_11.Value) str2double(app.EditField_12.Value) SlValue]; % Add Row of zeros
            
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.UITable.Data(end,:) = []; % Удалить строку
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.UITable.Data=[];
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            try  
                app.s = serial(string(app.DropDown.Items(app.DropDown.Value)),'BaudRate',115200);
                fopen(app.s);
                    
                app.Lamp.Color = 'green';
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';   
            end
           

        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            try
               app.Lamp.Color = 'red'; 
               fclose(app.s);
            catch 
               app.Lamp.Color = 'green';
               pause(0.2);
               app.Lamp.Color = 'red';
               pause(0.2);
               app.Lamp.Color = 'green';
               pause(0.2);
               app.Lamp.Color = 'red'; 
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % перемещение робота в домашнее положение
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes2_3);
            cla(app.UIAxes);
            
            app.UITable = [];
            Theta1 =  '0';
            Theta2 =  '0';
            Theta3 =  '0';
            SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3))
            fprintf(app.s, '%s', SendStringToSerial+'\r\n');
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.L.Base_Length = str2double(app.fEditField.Value)*0.455;
            app.L.EndEffector_Length = str2double(app.eEditField.Value)*0.5776;
            app.L.FirstLinc = str2double(app.r_eEditField.Value); 
            app.L.SecondLinc = str2double(app.r_fEditField.Value); 
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            out = evalin('base','out');
            XYZ = evalin('base','XYZ');
            plot3(app.UIAxes4,out.x_s.data,out.y_s.data,out.z_s.data,'LineWidth',1.5)
            hold(app.UIAxes4,'on');
            plot3(app.UIAxes4,XYZ(1,:),XYZ(2,:),XYZ(3,:),'red--','LineWidth',2)
            xlabel(app.UIAxes4,'OX, мм');
            ylabel(app.UIAxes4,'OY, мм');
            zlabel(app.UIAxes4,'OZ, мм');
%           legend(app.UIAxes4,'Смоделированная траектория','Рассчитанная траектория') 
            view(app.UIAxes4,-20.5,30);
            grid(app.UIAxes4,'on');
            
            % ошибка позиционирования
            plot(app.UIAxes5,out.ErorX.Time,out.ErorX.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes5,'t, s');
            ylabel(app.UIAxes5,'ΔX, мм');
            grid(app.UIAxes5,'on');
            
            plot(app.UIAxes6,out.ErorY.Time,out.ErorY.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes6,'t, s');
            ylabel(app.UIAxes6,'ΔY, мм');
            grid(app.UIAxes6,'on');
            
            plot(app.UIAxes6_2,out.ErorZ.Time,out.ErorZ.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes6_2,'t, s');
            ylabel(app.UIAxes6_2,'ΔZ, мм');
            grid(app.UIAxes6_2,'on');
            
            
            % графики обобщенных координат 
            % theta1
            plot(app.UIAxes7_4,out.q1.Time,out.q1.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes7_4,'t, s');
            ylabel(app.UIAxes7_4,'θ1, град.');
            grid(app.UIAxes7_4,'on');
            % theta2
            plot(app.UIAxes7_5,out.q2.Time,out.q2.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes7_5,'t, s');
            ylabel(app.UIAxes7_5,'θ2, град.');
            grid(app.UIAxes7_5,'on');
            % theta3
            plot(app.UIAxes8_2,out.q3.Time,out.q3.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes8_2,'t, s');
            ylabel(app.UIAxes8_2,'θ3, град.');
            grid(app.UIAxes8_2,'on');
            % X
            plot(app.UIAxes7,out.x_s.Time,out.x_s.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes7,'t, s');
            ylabel(app.UIAxes7,'X, мм');
            grid(app.UIAxes7,'on');
            % Y
            plot(app.UIAxes7_2,out.y_s.Time,out.y_s.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes7_2,'t, s');
            ylabel(app.UIAxes7_2,'Y, мм');
            grid(app.UIAxes7_2,'on');
            % Z
            plot(app.UIAxes8,out.z_s.Time,out.z_s.Data,'black','LineWidth',1.5)
            xlabel(app.UIAxes8,'t, s');
            ylabel(app.UIAxes8,'Z, мм');
            grid(app.UIAxes8,'on');
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.Button_5.Value || app.Button_4.Value)
                t1 = evalin('base','signal_T1(:,2)')
                t2 = evalin('base','signal_T2(:,2)')
                t3 = evalin('base','signal_T3(:,2)')
                quantOfPoints = length(t1)
                for i = 1:1:quantOfPoints
                    SendStringToSerial1 = num2str(round(t1(i)*10)/10);
                    SendStringToSerial2 = num2str(round(t2(i)*10)/10);
                    SendStringToSerial3 = num2str(round(t3(i)*10)/10);
                    SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                    fprintf(app.s, '%s', SendStringToSerial+'\r\n');
                    pause(0.01);
                end
            else
            app.StopWhile = false;
                quantOfPoints = length(app.UITable.Data(:,1));
                for i = 1:1:quantOfPoints
                    if (app.StopWhile)
                        break;
                    end
                    X = app.UITable.Data(i,1);
                    Y = app.UITable.Data(i,2);
                    Z = app.UITable.Data(i,3);
                    [theta1,theta2,theta3] = OZK(X, Y, Z,app.L);
                    SendStringToSerial1 = num2str(round(theta1*10)/10);
                    SendStringToSerial2 = num2str(round(theta2*10)/10);
                    SendStringToSerial3 = num2str(round(theta3*10)/10);
                    if app.Switch.Value == '1'
                        set_param('SimpleAb_2/Constant','Value',SendStringToSerial1);
                        set_param('SimpleAb_2/Constant1','Value',SendStringToSerial2);
                        set_param('SimpleAb_2/Constant2','Value',SendStringToSerial3);
                        SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                        fprintf(app.s, '%s', SendStringToSerial+'\r\n');
                        
                    else
                        SendStringToSerial = string(strcat(SendStringToSerial1,{' '},SendStringToSerial2,{' '},SendStringToSerial3));
                        fprintf(app.s, '%s', SendStringToSerial+'\r\n');
                    end
                    pause(1);
                end
            end
        end

        % Button pushed function: SimulinkButton
        function SimulinkButtonPushed(app, event)
            cd C:\Users\sulta\Desktop\Delta\NewDeltaModel\sm3\NewSimple3DModel\convert2
            if app.Switch.Value == '0'
                open('SimpleAb.slx');
            elseif app.Switch.Value == '1'
                open('SimpleAb_2.slx');
            else
            end
            app.Button_15.Enable = 'on';
            app.Button_16.Enable = 'on';
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes6_2);
            cla(app.UIAxes7);
            cla(app.UIAxes7_2);
            cla(app.UIAxes8);
            cla(app.UIAxes7_4);
            cla(app.UIAxes7_5);
            cla(app.UIAxes8_2);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            set_param('SimpleAb_2','SimulationCommand','Start')
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.StopWhile = true;
            pause(0.5);
            Theta1 =  '0';
            Theta2 =  '0';
            Theta3 =  '0';
            SendStringToSerial = string(strcat(Theta1,{' '},Theta2,{' '},Theta3))
            fprintf(app.s, '%s', SendStringToSerial+'\r\n');
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
           set_param('SimpleAb_2','SimulationCommand','Stop')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Управление';
            app.TabGroup2 = uitabgroup(app.Tab);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = 'Ввод';
            app.Panel_3 = uipanel(app.Tab_3);
            app.ButtonGroup = uibuttongroup(app.Panel_3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = 'Ручной ввод';
            app.Button_3.Value = true;
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = 'Окружность';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = 'Спираль';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = 'Квадрат';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = 'Ручной ввод';
            app.EditField_7 = uieditfield(app.Panel_3, 'text');
            app.EditField_8 = uieditfield(app.Panel_3, 'text');
            app.EditField_9 = uieditfield(app.Panel_3, 'text');
            app.XLabel_2 = uilabel(app.Panel_3);
            app.XLabel_2.Text = '  X';
            app.YLabel = uilabel(app.Panel_3);
            app.YLabel.Text = '  Y';
            app.ZLabel = uilabel(app.Panel_3);
            app.ZLabel.Text = '  Z';
            app.COMPanel = uipanel(app.Tab_3);
            app.COMPanel.Title = 'COM порт';
            app.DropDownLabel = uilabel(app.COMPanel);
            app.DropDownLabel.Text = 'Порт';
            app.DropDown = uidropdown(app.COMPanel);
            app.DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.DropDown.ItemsData = [1 2 3 4];
            app.DropDown.Value = 1;
            app.Button_7 = uibutton(app.COMPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = 'Подключиться';
            app.Button_8 = uibutton(app.COMPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = 'Отключиться';
            app.Lamp = uilamp(app.COMPanel);
            app.Panel_4 = uipanel(app.Tab_3);
            app.Panel_4.Title = 'Управление';
            app.XSlider = uislider(app.Panel_4);
            app.XSlider.Limits = [-80 80];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.Panel_4);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.Panel_4);
            app.YSlider.Limits = [-80 80];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.Panel_4);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.Panel_4);
            app.ZSlider.Limits = [-190 -100];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.Value = -120;
            app.Panel_5 = uipanel(app.Panel_4);
            app.EditField_13 = uieditfield(app.Panel_5, 'text');
            app.EditField_13.Value = '0';
            app.EditField_14 = uieditfield(app.Panel_5, 'text');
            app.EditField_14.Value = '0';
            app.EditField_15 = uieditfield(app.Panel_5, 'text');
            app.EditField_15.Value = '0';
            app.Label_6 = uilabel(app.Panel_5);
            app.Label_6.Text = ' θ1';
            app.Label_7 = uilabel(app.Panel_5);
            app.Label_7.Text = ' θ2';
            app.Label_8 = uilabel(app.Panel_5);
            app.Label_8.Text = ' θ3';
            app.Slider = uislider(app.Panel_5);
            app.Slider.Limits = [-40 40];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.Panel_5);
            app.Slider_2.Limits = [-40 40];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.Panel_5);
            app.Slider_3.Limits = [-40 40];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.EditField_10 = uieditfield(app.Panel_4, 'text');
            app.EditField_10.Value = '0';
            app.EditField_11 = uieditfield(app.Panel_4, 'text');
            app.EditField_11.Value = '0';
            app.EditField_12 = uieditfield(app.Panel_4, 'text');
            app.EditField_12.Value = '-120';
            app.XLabel_3 = uilabel(app.Panel_4);
            app.XLabel_3.Text = '  X';
            app.Panel_6 = uipanel(app.Tab_3);
            app.Button_9 = uibutton(app.Panel_6, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = 'Показать траекторию';
            app.UITable = uitable(app.Panel_6);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'; 'Захват'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Button_10 = uibutton(app.Panel_6, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = {'Добавить'; 'точку'};
            app.Button_11 = uibutton(app.Panel_6, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = {'Удалить'; 'точку'};
            app.Button_12 = uibutton(app.Panel_6, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = {'Очистка'; 'таблицы'};
            app.STARTButton = uibutton(app.Panel_6, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.Panel_6, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Button_13 = uibutton(app.Panel_6, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = 'Сброс';
            app.Switch_2 = uiswitch(app.Panel_6, 'slider');
            app.Switch_2.Items = {'off', 'on'};
            app.Switch_2.ItemsData = [0 1];
            app.Switch_2.Value = 0;
            app.Label_16 = uilabel(app.Panel_6);
            app.Label_16.Text = 'Захват';
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = 'Графики';
            app.UIAxes2 = uiaxes(app.Tab_4);
            title(app.UIAxes2, 'Проекция траектории на OXY')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Tab_4);
            title(app.UIAxes2_2, 'Проекция траектории на OXZ')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.YTick = [0 0.5 1];
            app.UIAxes2_3 = uiaxes(app.Tab_4);
            title(app.UIAxes2_3, 'Проекция траектории на OYZ')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.YTick = [0 0.5 1];
            app.UIAxes = uiaxes(app.Tab_4);
            title(app.UIAxes, '3D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Моделирование';
            app.TabGroup3 = uitabgroup(app.Tab_2);
            app.Tab_6 = uitab(app.TabGroup3);
            app.Tab_6.Title = 'Ввод';
            app.Panel_9 = uipanel(app.Tab_6);
            app.UIAxes4 = uiaxes(app.Panel_9);
            title(app.UIAxes4, 'Сравнение смоделированной и расчетной траектории')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.Panel_9);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.Panel_9);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6_2 = uiaxes(app.Panel_9);
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.Panel_10 = uipanel(app.Tab_6);
            app.SimulinkLabel = uilabel(app.Panel_10);
            app.SimulinkLabel.Text = {'Для получения графиков необходимо'; 'сначала открыть Simulink модель и нажать '; 'на кнопку запуска моделирования. После '; 'успешного выполнения данной операции кнопка'; 'построить графики станет доступной.'; ''; ''};
            app.SimulinkButton = uibutton(app.Panel_10, 'push');
            app.SimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @SimulinkButtonPushed, true);
            app.SimulinkButton.Text = 'Открыть модель в Simulink';
            app.SimulinkPanel = uipanel(app.Panel_10);
            app.SimulinkPanel.Title = 'Управление Simulink моделью';
            app.Button_15 = uibutton(app.SimulinkPanel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Enable = 'off';
            app.Button_15.Text = 'Запустить';
            app.Button_16 = uibutton(app.SimulinkPanel, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Enable = 'off';
            app.Button_16.Text = 'Остановить';
            app.Label_15 = uilabel(app.SimulinkPanel);
            app.Label_15.Text = 'Моделирование в реальном времени';
            app.Switch = uiswitch(app.SimulinkPanel, 'slider');
            app.Switch.Items = {'off', 'on'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.Value = '0';
            app.Button_17 = uibutton(app.Panel_10, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = 'Очистить графики';
            app.Button_18 = uibutton(app.Panel_10, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = 'Построить графики';
            app.Tab_7 = uitab(app.TabGroup3);
            app.Tab_7.Title = 'Графики';
            app.UIAxes7 = uiaxes(app.Tab_7);
            title(app.UIAxes7, 'X')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7_2 = uiaxes(app.Tab_7);
            title(app.UIAxes7_2, 'Y')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            app.UIAxes7_4 = uiaxes(app.Tab_7);
            title(app.UIAxes7_4, 'θ1')
            xlabel(app.UIAxes7_4, 'X')
            ylabel(app.UIAxes7_4, 'Y')
            zlabel(app.UIAxes7_4, 'Z')
            app.UIAxes7_5 = uiaxes(app.Tab_7);
            title(app.UIAxes7_5, 'θ2')
            xlabel(app.UIAxes7_5, 'X')
            ylabel(app.UIAxes7_5, 'Y')
            zlabel(app.UIAxes7_5, 'Z')
            app.UIAxes8 = uiaxes(app.Tab_7);
            title(app.UIAxes8, 'Z')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8_2 = uiaxes(app.Tab_7);
            title(app.UIAxes8_2, 'θ3')
            xlabel(app.UIAxes8_2, 'X')
            ylabel(app.UIAxes8_2, 'Y')
            zlabel(app.UIAxes8_2, 'Z')
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = 'Настройки';
            app.Panel_7 = uipanel(app.Tab_5);
            app.Panel_7.Title = 'Параметры дельта-робота';
            app.fEditFieldLabel = uilabel(app.Panel_7);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.Panel_7, 'text');
            app.fEditField.Value = '95.3';
            app.Label_9 = uilabel(app.Panel_7);
            app.Label_9.Text = 'мм';
            app.eEditFieldLabel = uilabel(app.Panel_7);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.Panel_7, 'text');
            app.eEditField.Value = '45.9';
            app.r_fEditFieldLabel = uilabel(app.Panel_7);
            app.r_fEditFieldLabel.Text = 'r_f';
            app.r_fEditField = uieditfield(app.Panel_7, 'text');
            app.r_fEditField.Value = '80';
            app.r_eEditFieldLabel = uilabel(app.Panel_7);
            app.r_eEditFieldLabel.Text = 'r_e';
            app.r_eEditField = uieditfield(app.Panel_7, 'text');
            app.r_eEditField.Value = '150';
            app.Label_10 = uilabel(app.Panel_7);
            app.Label_10.Text = 'мм';
            app.Label_11 = uilabel(app.Panel_7);
            app.Label_11.Text = 'мм';
            app.Label_12 = uilabel(app.Panel_7);
            app.Label_12.Text = 'мм';
            app.Image = uiimage(app.Tab_5);
            app.Image.ImageSource = 'DeltaParam.png';
            app.Panel_8 = uipanel(app.Tab_5);
            app.Panel_8.Title = 'Параметры моделирования';
            app.tEditField = uieditfield(app.Panel_8, 'text');
            app.tEditField.Value = '30';
            app.Label_13 = uilabel(app.Panel_8);
            app.Label_13.Text = 'Время моделирования';
            app.cLabel = uilabel(app.Panel_8);
            app.cLabel.Text = 'c';
            app.tEditFieldLabel_2 = uilabel(app.Panel_8);
            app.tEditFieldLabel_2.Text = 'N';
            app.tEditField_2 = uieditfield(app.Panel_8, 'text');
            app.tEditField_2.Value = '10';
            app.Label_14 = uilabel(app.Panel_8);
            app.Label_14.Text = 'Кол-во точек разбиения';
            app.tEditFieldLabel_3 = uilabel(app.Panel_8);
            app.tEditFieldLabel_3.Text = 't';
            app.Button_14 = uibutton(app.Tab_5, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = 'Сохранить изменения';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaRobotControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end